classdef Final_TestBed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CamaraDropDownLabel    matlab.ui.control.Label
        CamaraDropDown         matlab.ui.control.DropDown
        ConnectarButton        matlab.ui.control.Button
        CalibrarButton         matlab.ui.control.Button
        TomadePoseButton       matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        PararTomadePoseButton  matlab.ui.control.Button
    end


    properties (Access = public)
        nombreCamara = '';
        camara;
        tform;
        outviewref;
        funcionando = false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            camList = webcamlist;
            app.CamaraDropDown.Items = camList';
        end

        % Value changed function: CamaraDropDown
        function CamaraDropDownValueChanged(app, event)
            nombreDeCamara = app.CamaraDropDown.Value;
            camList = webcamlist;
            app.CamaraDropDown.Items = camList';
            app.nombreCamara = nombreDeCamara;
            disp(app.nombreCamara)
        end

        % Button pushed function: ConnectarButton
        function ConnectarButtonPushed(app, event)
            app.camara =  webcam(app.nombreCamara);
            app.CalibrarButton.Enable = true;
        end

        % Button pushed function: CalibrarButton
        function CalibrarButtonPushed(app, event)
            %I = snapshot(cam);
            load('Images.mat', 'I_goodLight_small');
            I = I_goodLight_small;
            
            [corners, ~, ~] = findCorners(I);

            fixed_points=[0 0; 640 0; 640 480; 0 480]; %puntos iniciales en la imagen original
            [moving_points] = orderCorners(corners(1:4,:), fixed_points);
            
            app.tform = fitgeotrans(moving_points,fixed_points,'projective');
            
            app.outviewref = imref2d(size(I));
            Itrans = imwarp(I, app.tform, 'OutputView', app.outviewref);
            figure(1); clf
            imshow(Itrans);
            title('Imagen Calibrada','FontWeight','normal')
            
            app.TomadePoseButton.Enable = true;
        end

        % Button pushed function: TomadePoseButton
        function TomadePoseButtonPushed(app, event)
%             pivotThresh = 140; highThresh = 60;
%             
%             %I = snapshot(cam);
%             load('Images.mat', 'I_goodLight_small');
%             I = I_goodLight_small;
%             
%             Itrans = imwarp(I, app.tform, 'OutputView', app.outviewref);
%             CalliBW = rgb2gray(Itrans);
%             CalliBW = edge(CalliBW,'Canny',0.5);
%             CalliBW = logical(iclose(CalliBW,kcircle(2)));
%             s = regionprops(CalliBW, 'Centroid');
%             centroids = cat(1, s.Centroid);
%             centroids([1 2],:) = [];
%             centroids([length(centroids)-1 length(centroids)],:) = [];
%             
%             Itrans = rgb2gray(Itrans);
%             [contours, ~] = getContours(CalliBW);
%             NumericIDs = zeros(1, floor(length(contours)/2) );
%             Angles= zeros(1, floor(length(contours)/2) );
%             
%             for i = 1:length(contours)
%                 if(mod(i, 2) ~= 0)
%                     
%                     angle = atan2(centroids(i,2)-centroids(i+1,2), centroids(i+1,1)-centroids(i,1));
%                     angle = rad2deg(angle);
%                     if(angle < 0)
%                         angle = angle + 360;
%                     end
%                     angle = angle - 45;
%                     Angles((i+1)/2) = angle;
%                     
%                     fixed_points=[0 0; 480 0; 480 480; 0 480]; %puntos iniciales en la imagen original
%                     moving_points = contours(num2str(i));
%             
%                     codeTForm = fitgeotrans(moving_points,fixed_points,'projective');
%             
%                     codeOutViewRef = imref2d([size(Itrans,1), size(Itrans,1)]);
%                     code = imwarp(Itrans, codeTForm, 'OutputView', codeOutViewRef);
%                     corners = [1/4, 1/4; 2/4, 1/4; 3/4, 1/4;
%                                1/4, 2/4; 2/4, 2/4; 3/4, 2/4;
%                                1/4, 3/4; 2/4, 3/4; 3/4, 3/4;].*flip(size(code));
%                     
%                     if(code(corners(3,2), corners(3,1)) > pivotThresh)
%                         code = imrotate(code, 90);
%                     elseif(code(corners(7,2), corners(7,1)) > pivotThresh)
%                         code = imrotate(code, -90);
%                     elseif(code(corners(9,2), corners(9,1)) > pivotThresh)
%                         code = imrotate(code, 180);
%                     end
%                     NumericID = zeros(1, 8);
%                     for k = 1:length(corners)-1
%                         if(code(corners(k+1,2), corners(k+1,1)) > highThresh)
%                             NumericID(k) = 1;
%                         else
%                             NumericID(k) = 0;
%                         end
%                     end
%                     NumericID = bi2de(NumericID, 'right-msb');
%                     NumericIDs((i+1)/2) = NumericID;
%                 end
%             end
%             
%             figure()
%             imshow(Itrans)
%             hold on
%             for i = 1:length(contours)
%                 if(mod(i, 2) ~= 0)
%                     viscircles(centroids(i,:), 5);
%                     text(centroids(i,1), centroids(i,2)+15, strcat('ID : ', num2str(NumericIDs((i+1)/2))),'Color','red','FontSize',14)
%                     text(centroids(i,1), centroids(i,2)+30, strcat('Ángulo : ', num2str(Angles((i+1)/2))), 'Color','red','FontSize',14)
%                     text(centroids(i,1), centroids(i,2)+45, strcat('Centroide : (', num2str(centroids(i,1)), ',', num2str(centroids(i,2)), ')'), 'Color','red','FontSize',14)
%                 end
%             end
%             title('Pose de códigos','FontWeight','normal')
            app.PararTomadePoseButton.Enable = true;
            app.funcionando = true;
            pivotThresh = 140; highThresh = 60;
            
            while(app.funcionando)
                %I = snapshot(cam);
                load('Images.mat', 'I_goodLight_small');
                I = I_goodLight_small;
                
                Itrans = imwarp(I, app.tform, 'OutputView', app.outviewref);
                CalliBW = rgb2gray(Itrans);
                CalliBW = edge(CalliBW,'Canny',0.5);
                CalliBW = logical(iclose(CalliBW,kcircle(2)));
                s = regionprops(CalliBW, 'Centroid');
                centroids = cat(1, s.Centroid);
                centroids([1 2],:) = [];
                centroids([length(centroids)-1 length(centroids)],:) = [];
                
                Itrans = rgb2gray(Itrans);
                [contours, ~] = getContours(CalliBW);
                NumericIDs = zeros(1, floor(length(contours)/2) );
                Angles= zeros(1, floor(length(contours)/2) );
                
                for i = 1:length(contours)
                    if(mod(i, 2) ~= 0)
                        
                        angle = atan2(centroids(i,2)-centroids(i+1,2), centroids(i+1,1)-centroids(i,1));
                        angle = rad2deg(angle);
                        if(angle < 0)
                            angle = angle + 360;
                        end
                        angle = angle - 45;
                        Angles((i+1)/2) = angle;
                        
                        fixed_points=[0 0; 480 0; 480 480; 0 480]; %puntos iniciales en la imagen original
                        moving_points = contours(num2str(i));
                
                        codeTForm = fitgeotrans(moving_points,fixed_points,'projective');
                
                        codeOutViewRef = imref2d([size(Itrans,1), size(Itrans,1)]);
                        code = imwarp(Itrans, codeTForm, 'OutputView', codeOutViewRef);
                        corners = [1/4, 1/4; 2/4, 1/4; 3/4, 1/4;
                                   1/4, 2/4; 2/4, 2/4; 3/4, 2/4;
                                   1/4, 3/4; 2/4, 3/4; 3/4, 3/4;].*flip(size(code));
                        
                        if(code(corners(3,2), corners(3,1)) > pivotThresh)
                            code = imrotate(code, 90);
                        elseif(code(corners(7,2), corners(7,1)) > pivotThresh)
                            code = imrotate(code, -90);
                        elseif(code(corners(9,2), corners(9,1)) > pivotThresh)
                            code = imrotate(code, 180);
                        end
                        NumericID = zeros(1, 8);
                        for k = 1:length(corners)-1
                            if(code(corners(k+1,2), corners(k+1,1)) > highThresh)
                                NumericID(k) = 1;
                            else
                                NumericID(k) = 0;
                            end
                        end
                        NumericID = bi2de(NumericID, 'right-msb');
                        NumericIDs((i+1)/2) = NumericID;
                    end
                end
                
                figure(1); clf
                imshow(Itrans)
                hold on
                for i = 1:length(contours)
                    if(mod(i, 2) ~= 0)
                        viscircles(centroids(i,:), 5);
                        text(centroids(i,1), centroids(i,2)+15, strcat('ID : ', num2str(NumericIDs((i+1)/2))),'Color','red','FontSize',14)
                        text(centroids(i,1), centroids(i,2)+30, strcat('Ángulo : ', num2str(Angles((i+1)/2))), 'Color','red','FontSize',14)
                        text(centroids(i,1), centroids(i,2)+45, strcat('Centroide : (', num2str(centroids(i,1)), ',', num2str(centroids(i,2)), ')'), 'Color','red','FontSize',14)
                    end
                end
                title('Pose de códigos','FontWeight','normal')
            end
        end

        % Button pushed function: PararTomadePoseButton
        function PararTomadePoseButtonPushed(app, event)
           app.funcionando = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CamaraDropDownLabel = uilabel(app.UIFigure);
            app.CamaraDropDownLabel.Text = 'Camara';
            app.CamaraDropDown = uidropdown(app.UIFigure);
            app.CamaraDropDown.ValueChangedFcn = createCallbackFcn(app, @CamaraDropDownValueChanged, true);
            app.ConnectarButton = uibutton(app.UIFigure, 'push');
            app.ConnectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectarButtonPushed, true);
            app.ConnectarButton.Text = 'Connectar';
            app.CalibrarButton = uibutton(app.UIFigure, 'push');
            app.CalibrarButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarButtonPushed, true);
            app.CalibrarButton.Enable = 'off';
            app.CalibrarButton.Text = 'Calibrar';
            app.TomadePoseButton = uibutton(app.UIFigure, 'push');
            app.TomadePoseButton.ButtonPushedFcn = createCallbackFcn(app, @TomadePoseButtonPushed, true);
            app.TomadePoseButton.Enable = 'off';
            app.TomadePoseButton.Text = 'Toma de Pose';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo002.png';
            app.PararTomadePoseButton = uibutton(app.UIFigure, 'push');
            app.PararTomadePoseButton.ButtonPushedFcn = createCallbackFcn(app, @PararTomadePoseButtonPushed, true);
            app.PararTomadePoseButton.Enable = 'off';
            app.PararTomadePoseButton.Text = 'Parar Toma de Pose';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_TestBed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end